classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        filtrineltempoTab               matlab.ui.container.Tab
        selezionaimmagineButton         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        filtromediaTab                  matlab.ui.container.Tab
        grandezzamatriceSpinner_2       matlab.ui.control.Spinner
        grandezzamatriceSpinner_2Label  matlab.ui.control.Label
        filtrogaussianoTab              matlab.ui.container.Tab
        grandezzamatriceSpinner         matlab.ui.control.Spinner
        grandezzamatriceSpinnerLabel    matlab.ui.control.Label
        filtraButton                    matlab.ui.control.Button
        sigmaSlider                     matlab.ui.control.Slider
        sigmaSliderLabel                matlab.ui.control.Label
        filtromedianoTab                matlab.ui.container.Tab
        grandezzamatriceSpinner_3       matlab.ui.control.Spinner
        grandezzamatriceSpinner_3Label  matlab.ui.control.Label
        filtripassaaltoTab              matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        filtriinfrequenzaTab            matlab.ui.container.Tab
        TabGroup2_2                     matlab.ui.container.TabGroup
        idealelpTab                     matlab.ui.container.Tab
        gaussianolpTab                  matlab.ui.container.Tab
        butterworthlpTab                matlab.ui.container.Tab
        idealehpTab                     matlab.ui.container.Tab
        gaussianohpTab                  matlab.ui.container.Tab
        butterworthhpTab                matlab.ui.container.Tab
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        trasformatawaveletTab           matlab.ui.container.Tab
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        imInput % immagine in input
        imOutput % ummagine in output
        grandezzaMatrice = 3 %inizializzo un valore di default per evitare che vada in errore
        sigma = 3 %inizializzo un valore di default per evitare che vada in errore
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selezionaimmagineButton
        function selezionaimmagineButtonPushed(app, event)
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            axis(app.UIAxes, 'image');
            % Display uigetfile dialog
            app.UIAxes2.Visible = 'off';
            app.UIAxes2.Colormap = gray(256);
            axis(app.UIAxes2, 'image');
            
            filterspec = {'*.jpg;*.tif;*.png;*.bmp;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            fname=[p f];
           
            app.imInput=imread(fname) ;
            imagesc(app.UIAxes,app.imInput);
        end

        % Button pushed function: filtraButton
        function filtroGaussianoLPSButtonPushed(app, event)
                    dim_gauss_filter = [app.grandezzaMatrice app.grandezzaMatrice]; % dim of filter
                    gauss_variance = app.sigma; % variance
                        H_gaussian = fspecial("gaussian", dim_gauss_filter, gauss_variance);
                    I_gaussian = imfilter(app.imInput, H_gaussian);
                    imagesc(app.UIAxes2,I_gaussian);
        end

        % Callback function
        function grandezzamatriceEditFieldValueChanged(app, event)
            value = app.grandezzamatriceEditField.Value;
            app.grandezzaMatrice = value;
        end

        % Value changing function: sigmaSlider
        function sigmaSliderValueChanging(app, event)
            changingValue = event.Value;
            dim_gauss_filter = [app.grandezzaMatrice app.grandezzaMatrice]; % dim of filter
            gauss_variance = changingValue; % variance
            H_gaussian = fspecial("gaussian", dim_gauss_filter, gauss_variance);
            I_gaussian = imfilter(app.imInput, H_gaussian);
            imagesc(app.UIAxes2,I_gaussian);
        end

        % Value changed function: grandezzamatriceSpinner
        function grandezzamatriceSpinnerValueChanged(app, event)
            value = app.grandezzamatriceSpinner.Value;
            app.grandezzaMatrice = value;
        end

        % Value changing function: grandezzamatriceSpinner
        function grandezzamatriceSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.grandezzaMatrice = changingValue;
            dim_gauss_filter = [app.grandezzaMatrice  app.grandezzaMatrice ]; % dim of filter
            gauss_variance = app.sigma; % variance
            H_gaussian = fspecial("gaussian", dim_gauss_filter, gauss_variance);
            I_gaussian = imfilter(app.imInput, H_gaussian);
            imagesc(app.UIAxes2,I_gaussian);
        end

        % Value changed function: sigmaSlider
        function sigmaSliderValueChanged(app, event)
            value = app.sigmaSlider.Value;
            app.sigma = value;
        end

        % Value changing function: grandezzamatriceSpinner_2
        function grandezzamatriceSpinner_MediaValueChanging(app, event)
            changingValue = event.Value;
           H_average = fspecial('average', changingValue); 
                % apply the average filter to the image
                I_average = imfilter(app.imInput, H_average);                
                I_average=uint8(I_average);
                imagesc(app.UIAxes2,I_average);
        end

        % Value changing function: grandezzamatriceSpinner_3
        function grandezzamatriceSpinner_3ValueChanging(app, event)
            changingValue = event.Value;
            app.grandezzaMatrice = changingValue;
            I_denoised = medfilt2(app.imInput, [app.grandezzaMatrice app.grandezzaMatrice]);
            imagesc(app.UIAxes2,I_denoised);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.filtrineltempoTab = uitab(app.TabGroup);
            app.filtrineltempoTab.Title = 'filtri nel tempo';
            app.UIAxes = uiaxes(app.filtrineltempoTab);
            title(app.UIAxes, 'INPUT')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.filtrineltempoTab);
            title(app.UIAxes2, 'OUTPUT')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.TabGroup2 = uitabgroup(app.filtrineltempoTab);
            app.filtromediaTab = uitab(app.TabGroup2);
            app.filtromediaTab.Title = 'filtro media ';
            app.grandezzamatriceSpinner_2Label = uilabel(app.filtromediaTab);
            app.grandezzamatriceSpinner_2Label.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner_2 = uispinner(app.filtromediaTab);
            app.grandezzamatriceSpinner_2.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinner_MediaValueChanging, true);
            app.grandezzamatriceSpinner_2.Limits = [1 256];
            app.grandezzamatriceSpinner_2.Value = 3;
            app.filtrogaussianoTab = uitab(app.TabGroup2);
            app.filtrogaussianoTab.Title = 'filtro gaussiano';
            app.sigmaSliderLabel = uilabel(app.filtrogaussianoTab);
            app.sigmaSliderLabel.Text = 'sigma';
            app.sigmaSlider = uislider(app.filtrogaussianoTab);
            app.sigmaSlider.Limits = [1 50];
            app.sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @sigmaSliderValueChanged, true);
            app.sigmaSlider.ValueChangingFcn = createCallbackFcn(app, @sigmaSliderValueChanging, true);
            app.sigmaSlider.Value = 5;
            app.filtraButton = uibutton(app.filtrogaussianoTab, 'push');
            app.filtraButton.ButtonPushedFcn = createCallbackFcn(app, @filtroGaussianoLPSButtonPushed, true);
            app.filtraButton.Text = 'filtra';
            app.grandezzamatriceSpinnerLabel = uilabel(app.filtrogaussianoTab);
            app.grandezzamatriceSpinnerLabel.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner = uispinner(app.filtrogaussianoTab);
            app.grandezzamatriceSpinner.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinnerValueChanging, true);
            app.grandezzamatriceSpinner.Limits = [1 256];
            app.grandezzamatriceSpinner.ValueChangedFcn = createCallbackFcn(app, @grandezzamatriceSpinnerValueChanged, true);
            app.grandezzamatriceSpinner.Value = 10;
            app.filtromedianoTab = uitab(app.TabGroup2);
            app.filtromedianoTab.Title = 'filtro mediano';
            app.grandezzamatriceSpinner_3Label = uilabel(app.filtromedianoTab);
            app.grandezzamatriceSpinner_3Label.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner_3 = uispinner(app.filtromedianoTab);
            app.grandezzamatriceSpinner_3.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinner_3ValueChanging, true);
            app.grandezzamatriceSpinner_3.Limits = [3 256];
            app.grandezzamatriceSpinner_3.Value = 3;
            app.filtripassaaltoTab = uitab(app.TabGroup2);
            app.filtripassaaltoTab.Title = 'filtri passa alto ';
            app.selezionaimmagineButton = uibutton(app.filtrineltempoTab, 'push');
            app.selezionaimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @selezionaimmagineButtonPushed, true);
            app.selezionaimmagineButton.Text = 'seleziona immagine';
            app.filtriinfrequenzaTab = uitab(app.TabGroup);
            app.filtriinfrequenzaTab.Title = 'filtri in frequenza';
            app.UIAxes_2 = uiaxes(app.filtriinfrequenzaTab);
            title(app.UIAxes_2, 'INPUT')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes2_2 = uiaxes(app.filtriinfrequenzaTab);
            title(app.UIAxes2_2, 'OUTPUT')
            app.UIAxes2_2.XLim = [0 1];
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.TabGroup2_2 = uitabgroup(app.filtriinfrequenzaTab);
            app.idealelpTab = uitab(app.TabGroup2_2);
            app.idealelpTab.Title = 'ideale lp';
            app.gaussianolpTab = uitab(app.TabGroup2_2);
            app.gaussianolpTab.Title = 'gaussiano lp';
            app.butterworthlpTab = uitab(app.TabGroup2_2);
            app.butterworthlpTab.Title = 'butterworth lp';
            app.idealehpTab = uitab(app.TabGroup2_2);
            app.idealehpTab.Title = 'ideale hp';
            app.gaussianohpTab = uitab(app.TabGroup2_2);
            app.gaussianohpTab.Title = 'gaussiano hp';
            app.butterworthhpTab = uitab(app.TabGroup2_2);
            app.butterworthhpTab.Title = 'butterworth hp';
            app.trasformatawaveletTab = uitab(app.TabGroup);
            app.trasformatawaveletTab.Title = 'trasformata wavelet';
            app.UIAxes_3 = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes_3, 'INPUT')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes2_3 = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes2_3, 'OUTPUT')
            app.UIAxes2_3.XLim = [0 1];
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end